classdef WiN6AFC_Agudemu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        info      matlab.ui.control.Label
        w1        matlab.ui.control.Button
        w2        matlab.ui.control.Button
        w3        matlab.ui.control.Button
        w4        matlab.ui.control.Button
        w5        matlab.ui.control.Button
        w6        matlab.ui.control.Button
        start     matlab.ui.control.Button
    end

    
    properties (Access = private)
        visit
        trial % trial number
        currtarget % The correct answer
        response % The answer picked by the subject
        currwords % The words in the list for current trial
        currsignal % The audio to be played for the trial
        currsnr % The visit (i.e., SNR played in the trial)
        currCond % The current speech-in-noise configuration type
        responsetable % Keeps full list of responses
        currwordlist
        totaltrials
        trialsPerBlock
        currblock
        totalBlocks
        trialaudiopath
        fs
        words % The full list of words
        feedbackdur
        subj
        respDir
        f1RZ % ActiveX figure for TDT soundcard
        RZ  % Soundcard object
        level % Sound level of target sound
        useTDT  % Whether or not to use the TDT
        stimrms = 0.01;
    end
    
    methods (Access = private)
        
        function dat = loadcurrtrial(app)
            fname = fullfile(app.trialaudiopath,...
                strcat('trial', num2str(app.trial), '.mat'));
            dat = load(fname);
        end
        
        function setupNextTrial(app)
            endOfBlock = (app.currblock*app.trialsPerBlock == app.trial);
            theEnd = app.trial == app.totaltrials;
            app.responsetable{app.trial, 1} = app.currwordlist;
            app.responsetable{app.trial, 2} = app.currtarget;
            app.responsetable{app.trial, 3} = app.response;
            app.responsetable{app.trial, 4} = app.currsnr;
            app.responsetable{app.trial, 5} = app.currCond;
            if ~theEnd
                app.trial = app.trial + 1;
                dat = app.loadcurrtrial();
                app.currtarget = dat.target;
                app.currsnr = dat.SNR;
                app.currCond = dat.configuration;
                app.currsignal = dat.y;
                app.currwords = app.words(dat.wordlist, :);
                app.currwordlist = dat.wordlist;
                if endOfBlock == true
                    app.betweenBlockPause()
                else
                    app.startButtonPushed();
                end
            else
                app.endCredits()
            end
        end
        
        function blankbuttons(app)
            app.w1.Text = '';
            app.w2.Text = '';
            app.w3.Text = '';
            app.w4.Text = '';
            app.w5.Text = '';
            app.w6.Text = '';
            app.w1.BackgroundColor = [0.96, 0.96, 0.96];
            app.w2.BackgroundColor = [0.96, 0.96, 0.96];
            app.w3.BackgroundColor = [0.96, 0.96, 0.96];
            app.w4.BackgroundColor = [0.96, 0.96, 0.96];
            app.w5.BackgroundColor = [0.96, 0.96, 0.96];
            app.w6.BackgroundColor = [0.96, 0.96, 0.96];
            app.w1.Enable = 'off';
            app.w2.Enable = 'off';
            app.w3.Enable = 'off';
            app.w4.Enable = 'off';
            app.w5.Enable = 'off';
            app.w6.Enable = 'off';
        end
        
        function betweenBlockPause(app)
            datetag = datestr(clock);
            datetag(strfind(datetag,' ')) = '_';
            datetag(strfind(datetag,':')) = '_';
            saveName = [app.respDir, '/', app.subj, '_block', num2str(app.currblock), '_', datetag, '.mat'];
            responseTable = app.responsetable;
            save(saveName, 'responseTable');
            infoText = ['Done with Block ', num2str(app.currblock), ' / ', num2str(app.totalBlocks)];
            app.info.Text = infoText;
            app.start.Text = 'Proceed ...';
            app.currblock = app.currblock + 1;
            app.start.Visible = 'on';
            app.start.Enable = 'on';
        end
        
        function endCredits(app)
            datetag = datestr(clock);
            datetag(strfind(datetag,' ')) = '_';
            datetag(strfind(datetag,':')) = '_';
            saveName = [app.respDir, '/', app.subj, '_block', num2str(app.currblock), '_', datetag, '.mat'];
            responseTable = app.responsetable;
            save(saveName, 'responseTable');
            infoText = {'Done with Experiment!', 'Thank you!'};
            app.info.Text = infoText;
            app.blankbuttons();
            pause(2.0);
            app.UIFigureCloseRequest();
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.useTDT = true;
            if app.useTDT
                %% Setup sound card
                fig_num=99;
                USB_ch=1;
                IAC = -1;
                FS_tag = 3;
                [app.f1RZ,app.RZ,~]=load_play_circuit(FS_tag,fig_num,USB_ch,0,IAC);
            end
            
            %% Setup experiment details
            app.level = 60;
            app.trialsPerBlock =114;
            app.totalBlocks = 4;
            app.totaltrials = app.trialsPerBlock * app.totalBlocks;
            load('./Words.mat');
            app.words = Words;
            app.fs = 48828;
            app.feedbackdur = 0.5;
            
            % Store full results in 4 columns
            % Column 1: Wordlist
            % Column 2: Target
            % Column 3: Response
            % Column 4: SNR
            app.responsetable = cell(app.totaltrials, 5);
            
            % Start with first trial
            startdat = load('./currsubj.mat');
            app.subj = startdat.subj;
            app.respDir = startdat.respDir;
            app.currblock = startdat.startBlock;
            app.visit = startdat.visit;
            app.trialaudiopath = strcat('C:/Experiments/Agudemu/audiofiles/', app.visit, '/');
            app.trial = (app.currblock - 1)*app.trialsPerBlock + 1;
            dat = app.loadcurrtrial();
            app.currtarget = dat.target;
            app.currsnr = dat.SNR;
            app.currCond = dat.configuration;
            app.currsignal = dat.y;
            app.currwords = app.words(dat.wordlist, :);
            app.currwordlist = dat.wordlist;
            
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            infoText = 'Please select the word that is prompted by the voice!';
            app.info.Text = infoText;
            app.blankbuttons();
            if ~app.useTDT
                % When not using TDT
                % Level is not calibrated
                sound(app.currsignal, app.fs);  
            else
                soundTDT(app.currsignal, app.RZ, app.level, app.stimrms);
            end
            fprintf(1, 'Running trial # %d / %d, which is block # %d ...\n',...
                app.trial, app.totaltrials, app.currblock);
            dur = size(app.currsignal, 1)/app.fs;
            pause(dur)
            app.w1.Text = app.currwords{1};
            app.w2.Text = app.currwords{2};
            app.w3.Text = app.currwords{3};
            app.w4.Text = app.currwords{4};
            app.w5.Text = app.currwords{5};
            app.w6.Text = app.currwords{6};
            app.w1.Enable = 'on';
            app.w2.Enable = 'on';
            app.w3.Enable = 'on';
            app.w4.Enable = 'on';
            app.w5.Enable = 'on';
            app.w6.Enable = 'on';
            app.start.Visible = 'off';
        end

        % Button pushed function: w1
        function w1ButtonPushed(app, event)
            app.response = 1;
            if app.response == app.currtarget
                app.w1.BackgroundColor = 'g';
            else
                app.w1.BackgroundColor = 'r';
            end
            pause(app.feedbackdur);
            if app.trial <= app.totaltrials
                app.setupNextTrial;
            end
        end

        % Button pushed function: w2
        function w2ButtonPushed(app, event)
            app.response = 2;
            if app.response == app.currtarget
                app.w2.BackgroundColor = 'g';
            else
                app.w2.BackgroundColor = 'r';
            end
            pause(app.feedbackdur);
            if app.trial <= app.totaltrials
                app.setupNextTrial;
            end
        end

        % Button pushed function: w3
        function w3ButtonPushed(app, event)
            app.response = 3;
            if app.response == app.currtarget
                app.w3.BackgroundColor = 'g';
            else
                app.w3.BackgroundColor = 'r';
            end
            pause(app.feedbackdur);
            if app.trial <= app.totaltrials
                app.setupNextTrial;
            end
        end

        % Button pushed function: w4
        function w4ButtonPushed(app, event)
            app.response = 4;
            if app.response == app.currtarget
                app.w4.BackgroundColor = 'g';
            else
                app.w4.BackgroundColor = 'r';
            end
            pause(app.feedbackdur);
            if app.trial <= app.totaltrials
                app.setupNextTrial;
            end
        end

        % Button pushed function: w5
        function w5ButtonPushed(app, event)
            app.response = 5;
            if app.response == app.currtarget
                app.w5.BackgroundColor = 'g';
            else
                app.w5.BackgroundColor = 'r';
            end
            pause(app.feedbackdur);
            if app.trial <= app.totaltrials
                app.setupNextTrial;
            end
        end

        % Button pushed function: w6
        function w6ButtonPushed(app, event)
            app.response = 6;
            if app.response == app.currtarget
                app.w6.BackgroundColor = 'g';
            else
                app.w6.BackgroundColor = 'r';
            end
            pause(app.feedbackdur);
            if app.trial <= app.totaltrials
                app.setupNextTrial;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close_play_circuit(app.f1RZ,app.RZ);
            if app.trial == app.totaltrials
                delete(app);
            else
                datetag = datestr(clock);
                datetag(strfind(datetag,' ')) = '_';
                datetag(strfind(datetag,':')) = '_';
                saveName = [app.respDir, '/', app.subj, '_crash_block', num2str(app.currblock), '_', datetag, '.mat'];
                responseTable = app.responsetable;
                save(saveName, 'responseTable');
                delete(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            setAutoResize(app, app.UIFigure, true)
            app.info = uilabel(app.UIFigure);
            app.info.Text = 'Please select the word that is prompted by the voice!';
            app.w1 = uibutton(app.UIFigure, 'push');
            app.w1.ButtonPushedFcn = createCallbackFcn(app, @w1ButtonPushed, true);
            app.w1.Enable = 'off';
            app.w1.Text = '';
            app.w2 = uibutton(app.UIFigure, 'push');
            app.w2.ButtonPushedFcn = createCallbackFcn(app, @w2ButtonPushed, true);
            app.w2.Enable = 'off';
            app.w2.Text = '';
            app.w3 = uibutton(app.UIFigure, 'push');
            app.w3.ButtonPushedFcn = createCallbackFcn(app, @w3ButtonPushed, true);
            app.w3.Enable = 'off';
            app.w3.Text = '';
            app.w4 = uibutton(app.UIFigure, 'push');
            app.w4.ButtonPushedFcn = createCallbackFcn(app, @w4ButtonPushed, true);
            app.w4.Enable = 'off';
            app.w4.Text = '';
            app.w5 = uibutton(app.UIFigure, 'push');
            app.w5.ButtonPushedFcn = createCallbackFcn(app, @w5ButtonPushed, true);
            app.w5.Enable = 'off';
            app.w5.Text = '';
            app.w6 = uibutton(app.UIFigure, 'push');
            app.w6.ButtonPushedFcn = createCallbackFcn(app, @w6ButtonPushed, true);
            app.w6.Enable = 'off';
            app.w6.Text = '';
            app.start = uibutton(app.UIFigure, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = 'START';
        end
    end

    methods (Access = public)

        % Construct app
        function app = WiN6AFC_Agudemu()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end